classdef SampSampGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        fileToolbar   matlab.ui.container.Menu
        saveFcn       matlab.ui.container.Menu
        loadFcn       matlab.ui.container.Menu
        exitFcn       matlab.ui.container.Menu
        graphToolbar  matlab.ui.container.Toolbar
        grabTool      matlab.ui.container.toolbar.ToggleTool
        zoomTool      matlab.ui.container.toolbar.ToggleTool
        pointerTool   matlab.ui.container.toolbar.ToggleTool
        openFile      matlab.ui.control.Button
        listbox       matlab.ui.control.ListBox
        TriggerPanel  matlab.ui.container.Panel
        trigOrNot     matlab.ui.control.DropDown
        text14        matlab.ui.control.Label
        text13        matlab.ui.control.Label
        triggVal      matlab.ui.control.EditField
        triggerValue  matlab.ui.control.Label
        stopTrig      matlab.ui.control.EditField
        stopValue     matlab.ui.control.Label
        AnalogInput   matlab.ui.container.ButtonGroup
        a0            matlab.ui.control.CheckBox
        a7            matlab.ui.control.CheckBox
        a6            matlab.ui.control.CheckBox
        a5            matlab.ui.control.CheckBox
        a4            matlab.ui.control.CheckBox
        a3            matlab.ui.control.CheckBox
        a2            matlab.ui.control.CheckBox
        a1            matlab.ui.control.CheckBox
        Save          matlab.ui.container.Panel
        name          matlab.ui.control.EditField
        openFolder    matlab.ui.control.Button
        saveDir       matlab.ui.control.Label
        startPanel    matlab.ui.container.Panel
        Device        matlab.ui.control.DropDown
        text12        matlab.ui.control.Label
        text11        matlab.ui.control.Label
        text10        matlab.ui.control.Label
        delSam        matlab.ui.control.EditField
        text9         matlab.ui.control.Label
        reset         matlab.ui.control.Button
        sec           matlab.ui.control.EditField
        TimeoutText   matlab.ui.control.Label
        freqText      matlab.ui.control.Label
        freq          matlab.ui.control.EditField
        startDAQ      matlab.ui.control.Button
        triggerPlot   matlab.ui.control.UIAxes
        allDataPlot   matlab.ui.control.UIAxes
        dataPlot      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function basicDaqRealTimePlotCallback(app, hObject, eventdata, handles, samHandles)
            if hObject.samplesAvailable>0
            
            
                %this callback for no trigger
            
                size = hObject.SamplesAvailable;
                data = peekdata(hObject,size);
                cla(samHandles.plotFigDataAxis);
                plot(samHandles.plotFigDataAxis,data(:,1))
                cla (samHandles.plotFigTrig);
                plot(samHandles.plotFigTrig,data(1:10:end,2));
            
            end
        end
        
        function daqRealTimePlotCallback(app, hObject, eventdata, handles, samHandles)
            % hObject    handle to daqrealtimeplot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % function daqrealtimeplot(hObject,eventdata)
            size = hObject.SamplesAvailable;
            if hObject.SamplesAvailable > 160
            
            
                lastSamps=peekdata(hObject, samHandles.stopSize);
                data=peekdata(hObject,size);
            
                if samHandles.aboveUnder
                    test = max(lastSamps);
                    test = test(1);
                else
                    test = min(lastSamps);
                    test = test(1);
                    disp hejyo
                end
                if test<samHandles.stopTrig
                    stop(hObject)
                    counter = getappdata(0,'counter');
                    samps = hObject.SamplesAvailable;
                    [data,time,abstime]=getdata(hObject,samps);
            %        data = [data; abstime'];
            
                    samHandles.fileNameData = [samHandles.dataBlock num2str(counter)];
                    %%%value to ticktimes
            
                    ticktimes_block = etime(abstime,[1970 01 01 0 0 0 ]);
            %        samHandles.fileNameMat =[ '' num2str(abstime(1)) '' num2str(abstime(2)) '' num2str(abstime(3)) 'T' num2str(abstime(4)) '' num2str(abstime(5)) '' num2str(abstime(6))];
                    %folder = getappdata(0,'folder');
            
                    fileNameData = [samHandles.dataBlock num2str(counter)];
                    fileNameTime = [samHandles.fileNameTime num2str(counter)];
            
                    eval([sprintf(fileNameData) '=data'';']);
                    eval([sprintf(fileNameTime) '=ticktimes_block;']);
            
                    save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat']...
                        ,fileNameData,fileNameTime,'-append');
            
            %         try
            %                save([ 'Z:\' samHandles.fileNameMat '.mat']...
            %                ,fileNameData,fileNameTime,'-append');
            %             catch
            %                 disp 'did not manage to connect to network drive Z: make sure you are logged in on network drive!';
            %             end
            
                    %---Save the handle structure-----
                    counter = counter + 1;
                    setappdata(0,'counter',counter);
                    setappdata(0,'samHandles',samHandles);
                    %---------------------------------
            
                    %---start the daq-object again-----
                    try
            
                        start(hObject)
                    catch
                        disp 'Timed out';
                    end
                    %----------------------------------
                end
            %     figure(samHandles.plotFigTrig);


                N=length(data(:,1));
                T=N/samHandles.freq;
                x=linspace(0,T,N);
            
                if T<11
                    cla(samHandles.plotFigTrigAxis)
                    axis(samHandles.plotFigTrigAxis,[0 11 0 4])
                    hold (samHandles.plotFigTrigAxis,'on')
            
                    plot(samHandles.plotFigTrigAxis,x,data(sprintf(samHandles.subPlot),1));
                    hold (samHandles.plotFigTrigAxis,'off')
                else
                    x2=linspace(T-10,T,10*samHandles.freq+1);
                    plot(samHandles.plotFigTrigAxis,x2,data(end-samHandles.freq*10:end,1));
                end
            
                try
            
                    if T<11
                        cla(samHandles.plotFigDataAxis);
                        axis(samHandles.plotFigDataAxis,[0 11 0 0.2])
            %             axis (samHandles.plotFigDataAxis,manual)
                        hold (samHandles.plotFigDataAxis,'on')
                        axis (samHandles.plotFigDataAxis,'auto y');
                        plot(samHandles.plotFigDataAxis,x,data(sprintf(samHandles.subPlot),2));
            
                        hold (samHandles.plotFigDataAxis,'off')
            
                    else
                        plot(samHandles.plotFigDataAxis,x2,data(end-samHandles.freq*10:end,2));
                    end
            
            
                end
            
            end
        end
        
        function drawLine_fcn(app, handles)
            
            try
                handles.lineXData = get(handles.line1,'XData');
            end
            
            handles.line1 = line('XData',handles.lineXData);
        end
        
        function edit8_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function freqTest_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to freqTest (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function missedData(app, hObject, eventdata, handles)
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit8 as text
            %        str2double(get(hObject,'String')) returns contents of edit8 as a double
            disp oj
        end
        
        function secTest_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to secTest (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function settings_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in settings.
            
            % hObject    handle to settings (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            run sampsampTrouble
        end
        
        function timerCallback(app, hObject, eventdata, handles)
            % varargout  cell array for returning output args (see VARARGOUT);
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get default command line output from handles structure
            % disp(rand)
            if ~isempty(daqfind)
                ai = daqfind;
                samHandles = getappdata(0,'samHandles');
                if strcmp(ai.Running, 'On') && samHandles.countOnOff>1
                    disp start
                    set(handles.startDAQ,'ForegroundColor', [0 1 0]);
                    samHandles.countOnOff = 0;
                end
                if strcmp(ai.Running, 'Off')&& samHandles.countOnOff<2
                    samHandles.countOnOff = samHandles.countOnOff + 1;
                    if samHandles.countOnOff>1
                       [data,time,abstime]=getdata(ai, ai.SamplesAvailable);
                    samHandles.fileNameData = [samHandles.dataBlock];
                    ticktimes_block = etime(abstime,[1970 01 01 0 0 0 ]);
            %        samHandles.fileNameMat =[ '' num2str(abstime(1)) '' num2str(abstime(2)) '' num2str(abstime(3)) 'T' num2str(abstime(4)) '' num2str(abstime(5)) '' num2str(abstime(6))];
                    %folder = getappdata(0,'folder');
            
                    fileNameData = [samHandles.dataBlock];
                    fileNameTime = [samHandles.fileNameTime];
            
                    eval([sprintf(fileNameData) '=data'';']);
                    eval([sprintf(fileNameTime) '=ticktimes_block;']);
            
                    save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat']...
                        ,fileNameData,fileNameTime,'-append');


                        disp stop
                        set(handles.startDAQ,'ForegroundColor',[1 0 0]);
            %             set(handles.startDAQ,'ForegroundColor',[1 0 0]);
                     end
                end
            
            setappdata(0,'samHandles',samHandles);
            end
        end
        
        function val1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to val1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function val2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to val2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.grabTool, app.zoomTool, app.pointerTool];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sampsamp_OpeningFcn(app, varargin)
            % --- Executes just before sampsamp is made visible.
            
            % Initialise default variables
            samHandles = initFcn();
            
            if ~exist('samHandles.nameNull', 'var')
                samHandles.nameNull = get(app.name,'String');
            end
            
            uiWindow=gcf;
            % Set access to figure window for easy editing
            setappdata(0,'samsam',uiWindow);
            % Set access to application info
            setappdata(0,'samHandles',samHandles);
            
            % Delete any existing timer objects to prevent errors
            if ~isempty(timerfind)
                stop(timerfind)
                delete(timerfind)
            end
            
        end

        % Menu selected function: exitFcn
        function exit_Callback(app, event)
            % --- Executes on button press in exit.
            close(gcf)
        end

        % Value changed function: listbox
        function listbox_Callback(app, event)
            % --- Executes on selection change in listbox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox
            string = get(handles.listbox,'String');
            val = get(handles.listbox,'Value');
            samHandles = getappdata(0,'samHandles');
            if val > 1
                load(handles.matFile,handles.files(val-1).name)
            
                plot(handles.allDataPlot,eval([handles.files(val-1).name '(1,:)']))
                %[handles]=drawLine_fcn(handles);
            end
            guidata(hObject,handles)
        end

        % Button pushed function: openFile
        function openFile_Callback(app, event)
            % --- Executes on button press in openFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to openFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [file, folder]= uigetfile('C:\-(sampsamp)-\q.mat');
            [file, folder]= uigetfile('E:\Yuri\q.mat');
            if isequal(file,0) || isequal(folder,0)
                disp('Operation canceled')
            else
            
                handles.matFile = [folder file];
                handles.files = whos ('-file', [folder file]);
                storlek = size(handles.files);
                N = (storlek(1)-1)/2;
            
                set(handles.listbox,'String',{'load new file'});
                for i = 1:N-3
                    oldString = get(handles.listbox,'String');
                    set(handles.listbox,'String', {oldString{:} handles.files(i).name})
                end
            end
            guidata(hObject,handles)
        end

        % Button pushed function: openFolder
        function openFolder_Callback(app, event)
            % --- Executes on button press in openFolder.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to openFolder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dir=uigetdir;
            if ~dir==0
                set(handles.saveDir,'String',dir);
            end
        end

        % Button pushed function: reset
        function reset_Callback(app, event)
            % --- Executes on button press in reset.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if ~isempty(daqfind)
                stop (daqfind);
            end
        end

        % Button pushed function: startDAQ
        function startDAQ_Callback(app, event)
            % --- Executes on button press in startDAQ.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to startDAQ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            daqreset;
            
            % setappdata(0,'stophand',hnadles
            samHandles=getappdata(0,'samHandles');
            
            %----------samHandlesRefresh updates the samHandles to user choices-------
            %##############own funktion samHandlesRefresh###############
            samHandles = sampHandelsRefresh(handles,samHandles);
            %-------------------------------------------------------------------------
            
            mkdir(samHandles.folderDir);
            
            info = '---------Data from sampsamp--------------';
            save([samHandles.folderDir '\' samHandles.fileNameMat '.mat'],'info');
            
            
            % ------------------------------------------------------
            scnsize = get(0,'screensize');
            
            samsam = getappdata(0,'samsam');
            posSamsam = get(samsam,'Position');
            outpossamsam = get(samsam,'OuterPosition');
            bordersamsam = outpossamsam - posSamsam;
            samsamSize = outpossamsam([3 4]);
            
            sizeAvFoFig = [(scnsize(3) - samsamSize(1)) (scnsize(4)*0.9)];
            
            % global hfig.fig1
            % samHandles.plotFigData=handles.dataPlot; %  pos outpos borders apsize newPos
            % samHandles.plotFigDataAxis = handles.dataPlot;
            % samHandles.plotFigTrig = handles.triggerPlot;
            % samHandles.plotFigTrigAxis = handles.triggerPlot;
            samHandles.plotFigData=handles.triggerPlot; %  pos outpos borders apsize newPos
            samHandles.plotFigDataAxis = handles.triggerPlot;
            samHandles.plotFigTrig = handles.dataPlot;
            samHandles.plotFigTrigAxis = handles.dataPlot;
            cla(samHandles.plotFigDataAxis);
            cla(samHandles.plotFigTrigAxis);
            files={''};
            setappdata(0,'Files',files);
            % %----------------------------Creates the ai----------------------------
            
            
            ai = analoginput(samHandles.deviceMan,samHandles.device);
            useTrigg = get(handles.trigg,'Value');

            [handles,samHandles, ai]=aiLoader(handles, samHandles, ai, useTrigg);
            %------------------------SET PROPERTIES OF AI------------------------------
            set(handles.freq,'String',get(ai,'SampleRate'))
            setappdata(0,'samHandles',samHandles);
            
            %-------------Timer funktion for running collor-----------
            
            if ~isempty(timerfind)
                stop(timerfind)
                delete(timerfind)
            end


            t = timer('TimerFcn', @(h, ev) timerCallback(app, h, ev, handles));
            t.executionMode = 'fixedRate';
            % set(t,'timerFcn',@app.timerCallback);
            t.Period = 0.25;
            %---------------------------------------------------------
            if get(handles.trigOrNot,'Value')==1 && get(handles.trigg,'Value')==1
                set(ai,'TimerFcn',{@app.daqRealTimePlotCallback,handles,samHandles})%or some reason this doesent work when in aiLoader
            else
                set(ai,'TimerFcn',{@app.basicDaqRealTimePlotCallback,handles,samHandles})
            end
            
            
            counter = 1;
            setappdata(0,'counter',counter);
            try
                start(ai);
                start(t);
                tic
            catch
                disp 'error';
            end


            if   get(handles.trigg,'Value')==0
                [data,time,abstime]=getdata(ai);
                stri = datestr(now,'HHMMSS');
                stri = ['C:\Documents and Settings\sampDev\Desktop\SAMPSAMP\' stri '.mat'];
                save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat'], 'data','time','abstime', '-append')
            
            end
        end

        % Value changed function: trigOrNot
        function trigOrNot_Callback(app, event)
            % --- Executes on selection change in trigOrNot.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to trigOrNot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns trigOrNot contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from trigOrNot
            val = get(handles.trigOrNot,'Value');
            switch val
                case 1
                    set(handles.triggerValue,'Visible','On')
                    set(handles.triggVal,'Visible','On')
                    set(handles.text13,'Visible','On')
                    set(handles.stopValue,'Visible','on')
                    set(handles.fromAbove,'Visible','On')
                    set(handles.fromUnder,'Visible','On')
                    set(handles.stopTrig,'Visible','On')
                    set(handles.text14,'Visible','On');
                case 2
                    set(handles.triggerValue,'Visible','Off')
                    set(handles.triggVal,'Visible','Off')
                    set(handles.text13,'Visible','off')
                    set(handles.stopValue,'Visible','off')
                    set(handles.fromAbove,'Visible','Off')
                    set(handles.fromUnder,'Visible','Off')
                    set(handles.stopTrig,'Visible','Off')
                    set(handles.text14,'Visible','Off');
            end
        end

        % Callback function: pointerTool
        function pointerTool_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.pointerTool.State;
            datacursormode(app.figure1, char(state));
        end

        % Callback function: zoomTool
        function zoomTool_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.zoomTool.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: grabTool
        function grabTool_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.grabTool.State;
            pan(app.figure1, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'sampsamp';
            app.figure1.Tag = 'figure1';
            app.fileToolbar = uimenu(app.figure1);
            app.fileToolbar.Text = 'File';
            app.fileToolbar.Tag = 'uimenu1';
            app.saveFcn = uimenu(app.fileToolbar);
            app.saveFcn.Text = 'Save Config';
            app.loadFcn = uimenu(app.fileToolbar);
            app.loadFcn.Text = 'Load Config';
            app.exitFcn = uimenu(app.fileToolbar);
            app.exitFcn.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exitFcn.Text = 'Exit';
            app.graphToolbar = uitoolbar(app.figure1);
            app.graphToolbar.Tag = 'uitoolbar1';
            app.grabTool = uitoggletool(app.graphToolbar);
            app.grabTool.Tag = 'uitoggletool1';
            app.grabTool.Tooltip = 'Pan';
            app.grabTool.ClickedCallback = createCallbackFcn(app, @grabTool_ClickedCallback, true);
            app.grabTool.Icon = 'uitoggletool1_image.png';
            app.zoomTool = uitoggletool(app.graphToolbar);
            app.zoomTool.Tag = 'uitoggletool2';
            app.zoomTool.Tooltip = 'Zoom In';
            app.zoomTool.ClickedCallback = createCallbackFcn(app, @zoomTool_ClickedCallback, true);
            app.zoomTool.Icon = 'uitoggletool2_image.png';
            app.pointerTool = uitoggletool(app.graphToolbar);
            app.pointerTool.Tag = 'uitoggletool3';
            app.pointerTool.Tooltip = 'Data Cursor';
            app.pointerTool.ClickedCallback = createCallbackFcn(app, @pointerTool_ClickedCallback, true);
            app.pointerTool.Icon = 'uitoggletool3_image.png';
            app.dataPlot = uiaxes(app.figure1);
            app.dataPlot.Tag = 'dataPlot';
            app.allDataPlot = uiaxes(app.figure1);
            app.allDataPlot.Tag = 'allDataPlot';
            app.triggerPlot = uiaxes(app.figure1);
            app.triggerPlot.Tag = 'triggerPlot';
            app.startPanel = uipanel(app.figure1);
            app.startPanel.Title = 'Sampling';
            app.startPanel.Tag = 'startPanel';
            app.startDAQ = uibutton(app.startPanel, 'push');
            app.startDAQ.ButtonPushedFcn = createCallbackFcn(app, @startDAQ_Callback, true);
            app.startDAQ.Tag = 'startDAQ';
            app.startDAQ.Text = 'Start-DAQ';
            app.freq = uieditfield(app.startPanel, 'text');
            app.freq.Tag = 'freq';
            app.freq.Value = '40000';
            app.freqText = uilabel(app.startPanel);
            app.freqText.Tag = 'freqText';
            app.freqText.Text = 'f [Hz]';
            app.TimeoutText = uilabel(app.startPanel);
            app.TimeoutText.Tag = 'TimeoutText';
            app.TimeoutText.Text = 'Timeout';
            app.sec = uieditfield(app.startPanel, 'text');
            app.sec.Tag = 'sec';
            app.sec.Value = '1200';
            app.reset = uibutton(app.startPanel, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @reset_Callback, true);
            app.reset.Tag = 'reset';
            app.reset.Text = 'Stop-DAQ';
            app.text9 = uilabel(app.startPanel);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Delay';
            app.delSam = uieditfield(app.startPanel, 'text');
            app.delSam.Tag = 'delSam';
            app.delSam.Value = '25';
            app.text10 = uilabel(app.startPanel);
            app.text10.Tag = 'text10';
            app.text10.Text = '[Hz]';
            app.text11 = uilabel(app.startPanel);
            app.text11.Tag = 'text11';
            app.text11.Text = '[s]';
            app.text12 = uilabel(app.startPanel);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Samples';
            app.Device = uidropdown(app.startPanel);
            app.Device.Items = {'Dev1', 'Dev2', 'Dev3'};
            app.Device.Tag = 'Device';
            app.Device.Value = 'Dev2';
            app.Save = uipanel(app.figure1);
            app.Save.Title = 'Save Options';
            app.Save.Tag = 'Save';
            app.saveDir = uilabel(app.Save);
            app.saveDir.Tag = 'saveDir';
            app.saveDir.Text = 'E:\Yuri';
            app.openFolder = uibutton(app.Save, 'push');
            app.openFolder.ButtonPushedFcn = createCallbackFcn(app, @openFolder_Callback, true);
            app.openFolder.Tag = 'openFolder';
            app.openFolder.Text = 'Open folder';
            app.name = uieditfield(app.Save, 'text');
            app.name.Tag = 'name';
            app.name.Value = 'TagnameOfFile';
            app.AnalogInput = uibuttongroup(app.figure1);
            app.AnalogInput.Title = 'Analog input';
            app.AnalogInput.Tag = 'AnalogInput';
            app.a1 = uicheckbox(app.AnalogInput);
            app.a1.Tag = 'a1';
            app.a1.Text = 'a1';
            app.a2 = uicheckbox(app.AnalogInput);
            app.a2.Tag = 'a2';
            app.a2.Text = 'a2';
            app.a3 = uicheckbox(app.AnalogInput);
            app.a3.Tag = 'a3';
            app.a3.Text = 'a3';
            app.a4 = uicheckbox(app.AnalogInput);
            app.a4.Tag = 'a4';
            app.a4.Text = 'a4';
            app.a5 = uicheckbox(app.AnalogInput);
            app.a5.Tag = 'a5';
            app.a5.Text = 'a5';
            app.a6 = uicheckbox(app.AnalogInput);
            app.a6.Tag = 'a6';
            app.a6.Text = 'a6';
            app.a7 = uicheckbox(app.AnalogInput);
            app.a7.Tag = 'a7';
            app.a7.Text = 'a7';
            app.a7.Value = true;
            app.a0 = uicheckbox(app.AnalogInput);
            app.a0.Tag = 'a0';
            app.a0.Enable = 'off';
            app.a0.Text = 'a0';
            app.a0.Value = true;
            app.TriggerPanel = uipanel(app.figure1);
            app.TriggerPanel.Title = 'Trigger setings';
            app.TriggerPanel.Tag = 'TriggerPanel';
            app.stopValue = uilabel(app.TriggerPanel);
            app.stopValue.Tag = 'stopValue';
            app.stopValue.Text = 'Stop when: ';
            app.stopTrig = uieditfield(app.TriggerPanel, 'text');
            app.stopTrig.Tag = 'stopTrig';
            app.stopTrig.Value = '3.5';
            app.triggerValue = uilabel(app.TriggerPanel);
            app.triggerValue.Tag = 'triggerValue';
            app.triggerValue.Text = 'Start when trig >';
            app.triggVal = uieditfield(app.TriggerPanel, 'text');
            app.triggVal.Tag = 'triggVal';
            app.triggVal.Value = '3';
            app.text13 = uilabel(app.TriggerPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = '[V]';
            app.text14 = uilabel(app.TriggerPanel);
            app.text14.Tag = 'text14';
            app.text14.Text = '[V]';
            app.trigOrNot = uidropdown(app.TriggerPanel);
            app.trigOrNot.Items = {'With trigger', 'no trigger'};
            app.trigOrNot.ValueChangedFcn = createCallbackFcn(app, @trigOrNot_Callback, true);
            app.trigOrNot.Tag = 'trigOrNot';
            app.trigOrNot.Value = 'With trigger';
            app.listbox = uilistbox(app.figure1);
            app.listbox.Items = {'Listbox'};
            app.listbox.ValueChangedFcn = createCallbackFcn(app, @listbox_Callback, true);
            app.listbox.Tag = 'listbox';
            app.listbox.Value = 'Listbox';
            app.openFile = uibutton(app.figure1, 'push');
            app.openFile.ButtonPushedFcn = createCallbackFcn(app, @openFile_Callback, true);
            app.openFile.Tag = 'openFile';
            app.openFile.Text = 'Open file';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SampSampGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)sampsamp_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end